classdef appMaquinaPVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ButtonGroup       matlab.ui.container.ButtonGroup
        EulerButton       matlab.ui.control.RadioButton
        EulerButton_2     matlab.ui.control.RadioButton
        RK2Button         matlab.ui.control.RadioButton
        RK4Button         matlab.ui.control.RadioButton
        ODE45Button       matlab.ui.control.RadioButton
        PSMButton         matlab.ui.control.RadioButton
        TodosButton       matlab.ui.control.RadioButton
        LimparButton      matlab.ui.control.Button
        CalcularButton    matlab.ui.control.Button
        MtodosLabel       matlab.ui.control.Label
        func              matlab.ui.control.EditField
        ftyLabel          matlab.ui.control.Label
        DadosdoPVILabel   matlab.ui.control.Label
        UITable           matlab.ui.control.Table
        aEditFieldLabel   matlab.ui.control.Label
        a_field           matlab.ui.control.NumericEditField
        bEditFieldLabel   matlab.ui.control.Label
        b_field           matlab.ui.control.NumericEditField
        nEditFieldLabel   matlab.ui.control.Label
        n_field           matlab.ui.control.NumericEditField
        y0EditFieldLabel  matlab.ui.control.Label
        y0_field          matlab.ui.control.NumericEditField
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
            addpath './Métodos';
            
            cla(app.UIAxes);
            
            syms y(t)
            
            strF = app.func.Value;
            f = @(t,y) eval(vectorize(strF));
            try
                f(t,y);
            catch
                errordlg("Introduza uma função em t e y","Erro");
                return
            end
            
            a = app.a_field.Value;
            if (not(isscalar(a) && isreal(a)))
                errordlg("O número introduzido para a é inválido","Erro");
                return
            end
            
            b = app.b_field.Value;
            if (not(isscalar(b) && isreal(b) && b>a))
                errordlg("O número introduzido para b é inválido","Erro");
                return
            end
        
            n = app.n_field.Value;
            if (not(isscalar(n) && isreal(n)))
                errordlg("O número introduzido para n é inválido","Erro");
                return
            end
        
            y0 = app.y0_field.Value;
            if (not(isscalar(y0) && isreal(y0))) 
                errordlg("O número introduzido para y0 é inválido","Erro");
                return
            end
            

            if app.EulerButton.Value
                escolha = "Euler";
            elseif app.EulerButton_2.Value
                escolha = "Euler+";
            elseif app.RK2Button.Value
                escolha = "RK2";
            elseif app.RK4Button.Value
                escolha = "RK4";
            elseif app.ODE45Button.Value
                escolha = "ODE45";
            elseif app.PSMButton.Value
                escolha = "PSM";
            elseif app.TodosButton.Value
                escolha = "Todos";
            end
            
            xExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
            g =@(t) eval(vectorize(char(xExata)));
            h = (b-a)/n;
            t = a:h:b;
            yExata = g(t);
            plot(app.UIAxes,yExata,'-r');
            hold(app.UIAxes, "on");
            
            switch escolha
                case "Euler"
                    sol = MEuler(f,a,b,n,y0);
                    plot(app.UIAxes,sol,'-b');
                    legend(app.UIAxes,'Exata','Euler');
                    hold(app.UIAxes, "on");
                case "Euler+"
                    sol = MEulerMelhorado(f,a,b,n,y0);
                    plot(app.UIAxes,sol,'-b');
                    legend(app.UIAxes,'Exata','Euler+');
                    hold(app.UIAxes, "on");
                case "RK2"
                    sol = NRK2(f,a,b,n,y0);
                    plot(app.UIAxes,sol,'-b');
                    legend(app.UIAxes,'Exata','RK2');
                    hold(app.UIAxes, "on");
                case "RK4"
                    sol = NRK4(f,a,b,n,y0);
                    plot(app.UIAxes,sol,'-b');
                    legend(app.UIAxes,'Exata','RK4');
                    hold(app.UIAxes, "on");
                case "ODE45"
                    sol = ode45(f,[a b],y0);
                    plot(app.UIAxes,sol.y,'-b');
                    legend(app.UIAxes,'Exata','ODE45');
                    hold(app.UIAxes, "on");
                case "PSM"
                    %%%%%%%%
                case "Todos"
                    Euler = MEuler(f,a,b,n,y0);
                    EulerM = MEulerMelhorado(f,a,b,n,y0);
                    RK2 = NRK2(f,a,b,n,y0);
                    RK4 = NRK4(f,a,b,n,y0);
                    ODE45 = ode45(f,[a b],y0);
                    plot(app.UIAxes,Euler);
                    plot(app.UIAxes,EulerM);
                    plot(app.UIAxes,RK2);
                    plot(app.UIAxes,RK4);
                    plot(app.UIAxes,ODE45.y);
                    legend(app.UIAxes,'Exata','Euler','Euler+','RK2','RK4','ODE45');
                    hold(app.UIAxes, "on");
            end

        end

        % Button pushed function: LimparButton
        function LimparButtonPushed(app, event)
                cla(app.UIAxes);
                app.y0_field.Value=0;
                app.n_field.Value=0;
                app.b_field.Value=0;
                app.a_field.Value=0;
                app.func.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.EulerButton = uiradiobutton(app.ButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.EulerButton_2 = uiradiobutton(app.ButtonGroup);
            app.EulerButton_2.Text = 'Euler +';
            app.RK2Button = uiradiobutton(app.ButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uiradiobutton(app.ButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.ODE45Button = uiradiobutton(app.ButtonGroup);
            app.ODE45Button.Text = 'ODE45';
            app.PSMButton = uiradiobutton(app.ButtonGroup);
            app.PSMButton.Text = 'PSM';
            app.TodosButton = uiradiobutton(app.ButtonGroup);
            app.TodosButton.Text = 'Todos';
            app.LimparButton = uibutton(app.UIFigure, 'push');
            app.LimparButton.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.LimparButton.Text = 'Limpar';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.MtodosLabel = uilabel(app.UIFigure);
            app.MtodosLabel.Text = 'Métodos';
            app.func = uieditfield(app.UIFigure, 'text');
            app.func.Value = 'y+exp(3*t)';
            app.ftyLabel = uilabel(app.UIFigure);
            app.ftyLabel.Text = 'f(t, y)';
            app.DadosdoPVILabel = uilabel(app.UIFigure);
            app.DadosdoPVILabel.Text = 'Dados do PVI';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.a_field = uieditfield(app.UIFigure, 'numeric');
            app.a_field.Value = 1;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.b_field = uieditfield(app.UIFigure, 'numeric');
            app.b_field.Value = 1.5;
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.n_field = uieditfield(app.UIFigure, 'numeric');
            app.n_field.Value = 20;
            app.y0EditFieldLabel = uilabel(app.UIFigure);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0_field = uieditfield(app.UIFigure, 'numeric');
            app.y0_field.Value = 2;
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaPVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end